classdef rgb_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                    % Instru...
        Axes                   matlab.ui.control.UIAxes            % Values
        Panel                  matlab.ui.container.Panel           % RED LED
        K1                     matlab.ui.control.Knob              % [0 100]
        L1                     matlab.ui.control.Lamp             
        Sw1                    matlab.ui.control.Switch            % Off, On
        Panel2                 matlab.ui.container.Panel           % GREEN LED
        Sw2                    matlab.ui.control.Switch            % Off, On
        K2                     matlab.ui.control.Knob              % [0 100]
        L2                     matlab.ui.control.Lamp             
        Panel3                 matlab.ui.container.Panel           % BLUE LED
        Sw3                    matlab.ui.control.Switch            % Off, On
        K3                     matlab.ui.control.Knob              % [0 100]
        L3                     matlab.ui.control.Lamp             
        Panel4                 matlab.ui.container.Panel           %       ...
        BDiscon                matlab.ui.control.Button            % Discon...
        BCon                   matlab.ui.control.Button            % Connect
        LabelDropDown          matlab.ui.control.Label             % Port
        PortList               matlab.ui.control.DropDown          % Option...
        LRGB                   matlab.ui.control.Lamp             
        Lamp                   matlab.ui.control.Lamp             
        Label                  matlab.ui.control.Label             % RGB
        LightEdit              matlab.ui.control.NumericEditField  % [-Inf ...
        LabelSemicircularGauge matlab.ui.control.Label             % Illumi...
        LightGauge             matlab.ui.control.SemicircularGauge % [0 100]
    end


    properties (Access = private)
        s % serial port
        v1 % knobs values
        v2
        v3
        v1a % knobs values arrays
        v2a
        v3a
        la % light sensor value
        lval % light sensor values array
        time % time array
        t % timer
    end

    methods (Access = private)
        
        function SendData(app)
            % read color values from GUI
            val1 = app.v1 * app.Sw1.Value;
            val2 = app.v2 * app.Sw2.Value;
            val3 = app.v3 * app.Sw3.Value;
            % update color values arrays
            app.v1a = [app.v1a(2 : end) val1];
            app.v2a = [app.v2a(2 : end) val2];
            app.v3a = [app.v3a(2 : end) val3];
            % update light sensor values array
            app.la = [app.la(2 : end) app.lval];
            % plot signals in GUI
            plot(app.Axes, app.time', [app.v1a; app.v2a; app.v3a; app.la]');
            ylim(app.Axes, [0 100]);
            % update LightGauge and LightEdit
            app.LightGauge.Value = app.la(end);
            app.LightEdit.Value = app.la(end);
            % change color of GUI RGB lamp
            app.LRGB.Color = [val1 val2 val3] / 100;
            % if output buffer is empty write values to serial port in asyncronous mode
            if app.s.BytesToOutput == 0
                fwrite(app.s, [val1 val2 val3], 'uint8', 'async');
            end
        end

        function ReadData(app)
            % read and scale data from serial port 
            app.lval = double(fread(app.s, 1, 'uint16')) / 10;
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % fixed time step of calling SendData function
            time_step = 0.07;
            % set up swithes values
            app.Sw1.ItemsData = [0 1];
            app.Sw2.ItemsData = [0 1];
            app.Sw3.ItemsData = [0 1];
            % read knob values
            app.v1 = app.K1.Value;
            app.v2 = app.K2.Value;
            app.v3 = app.K3.Value;
            % define time array for plotting
            app.time = 0 : time_step : 10;
            % define color and light sensor values arrays for plotting
            app.v1a = app.time * 0;
            app.v2a = app.time * 0;
            app.v3a = app.time * 0;
            app.la = app.time * 0;
            % define light sensor value
            app.lval = 0;
            % define lamps colors
            app.L1.Color = [0.0314 0.8275 0.0314];
            app.L2.Color = [0.0314 0.8275 0.0314];
            app.L3.Color = [0.0314 0.8275 0.0314];
            % define color order fot plotting            
            app.Axes.ColorOrder = [0.850,  0.325,  0.098; ...
                                   0.466,  0.674,  0.188; ...
                                   0.000,  0.447,  0.741; ...
                                   0.839,  0.839,  0.024];
            % timer set up
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.SendData;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = time_step;
            app.t.BusyMode = 'drop';
            % looking for available serial ports (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % BDiscon button pushed function
        function BDisconButtonPushed(app)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.t.running, 'on')
                stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % BCon button pushed function
        function BConButtonPushed(app)
            % on connect open serial port, start timer and change lamp color to green
            if strcmp(app.t.running, 'off')
                app.s = serial(app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BytesAvailableFcnCount = 2;
                app.s.BytesAvailableFcnMode = 'byte';
                app.s.BytesAvailableFcn = @(~, ~)app.ReadData;
                fopen(app.s);
                start(app.t);
                app.Lamp.Color = [0.00,1.00,0.00];
            end
        end

        % K1 value changing function
        function K1ValueChanging(app, event)
            % read knob 1 value (red color) if it is changing
            app.v1 = event.Value;            
        end

        % Knob value changing function
        function K2ValueChanging(app, event)
            % read knob 2 value (green color) if it is changing
            app.v2 = event.Value;            
        end

        % K3 value changing function
        function K3ValueChanging(app, event)
            % read knob 3 value (blue color) if it is changing
            app.v3 = event.Value;            
        end

        % Sw1 value changed function
        function Sw1ValueChanged(app)
            % change lamp color if switch value has changed
            value = app.Sw1.Value;
            if value, app.L1.Color = [0.0314 0.8275 0.0314];
            else app.L1.Color = [0.4706 0.5059 0.4706]; end
        end

        % Sw2 value changed function
        function Sw2ValueChanged(app)
            % change lamp color if switch value has changed
            value = app.Sw2.Value;
            if value, app.L2.Color = [0.0314 0.8275 0.0314];
            else app.L2.Color = [0.4706 0.5059 0.4706]; end
        end

        % Sw3 value changed function
        function Sw3ValueChanged(app)
            % change lamp color if switch value has changed
            value = app.Sw3.Value;
            if value, app.L3.Color = [0.0314 0.8275 0.0314];
            else app.L3.Color = [0.4706 0.5059 0.4706]; end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Instrument Control over Serial';
            setAutoResize(app, app.UIFigure, true)
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Values');
            xlabel(app.Axes, 'Time, sec');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'RED LED';
            app.Panel.Units = 'pixels';
            app.K1 = uiknob(app.Panel, 'continuous');
            app.K1.ValueChangingFcn = createCallbackFcn(app, @K1ValueChanging, true);
            app.K1.Value = 100;
            app.L1 = uilamp(app.Panel);
            app.Sw1 = uiswitch(app.Panel, 'slider');
            app.Sw1.ValueChangedFcn = createCallbackFcn(app, @Sw1ValueChanged);
            app.Sw1.Value = 'On';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'GREEN LED';
            app.Panel2.Units = 'pixels';
            app.Sw2 = uiswitch(app.Panel2, 'slider');
            app.Sw2.ValueChangedFcn = createCallbackFcn(app, @Sw2ValueChanged);
            app.Sw2.Value = 'On';
            app.K2 = uiknob(app.Panel2, 'continuous');
            app.K2.ValueChangingFcn = createCallbackFcn(app, @K2ValueChanging, true);
            app.K2.Value = 100;
            app.L2 = uilamp(app.Panel2);
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'BLUE LED';
            app.Panel3.Units = 'pixels';
            app.Sw3 = uiswitch(app.Panel3, 'slider');
            app.Sw3.ValueChangedFcn = createCallbackFcn(app, @Sw3ValueChanged);
            app.Sw3.Value = 'On';
            app.K3 = uiknob(app.Panel3, 'continuous');
            app.K3.ValueChangingFcn = createCallbackFcn(app, @K3ValueChanging, true);
            app.K3.Value = 100;
            app.L3 = uilamp(app.Panel3);
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = '      Connection';
            app.Panel4.Units = 'pixels';
            app.BDiscon = uibutton(app.Panel4, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed);
            app.BDiscon.Text = 'Disconnect';
            app.BCon = uibutton(app.Panel4, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed);
            app.BCon.Text = 'Connect';
            app.LabelDropDown = uilabel(app.Panel4);
            app.LabelDropDown.Text = 'Port';
            app.PortList = uidropdown(app.Panel4);
            app.LRGB = uilamp(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'RGB';
            app.LightEdit = uieditfield(app.UIFigure, 'numeric');
            app.LightEdit.ValueDisplayFormat = '%.0f';
            app.LightEdit.Editable = 'off';
            app.LabelSemicircularGauge = uilabel(app.UIFigure);
            app.LabelSemicircularGauge.Text = 'Illuminance';
            app.LightGauge = uigauge(app.UIFigure, 'semicircular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = rgb_control()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end