classdef rgb_control_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Axes                    matlab.ui.control.UIAxes
        PanelR                  matlab.ui.container.Panel
        K1                      matlab.ui.control.Knob
        L1                      matlab.ui.control.Lamp
        Sw1                     matlab.ui.control.Switch
        PanelG                  matlab.ui.container.Panel
        Sw2                     matlab.ui.control.Switch
        K2                      matlab.ui.control.Knob
        L2                      matlab.ui.control.Lamp
        PanelB                  matlab.ui.container.Panel
        Sw3                     matlab.ui.control.Switch
        K3                      matlab.ui.control.Knob
        L3                      matlab.ui.control.Lamp
        PanelCon                matlab.ui.container.Panel
        BDiscon                 matlab.ui.control.Button
        BCon                    matlab.ui.control.Button
        LabelDropDown           matlab.ui.control.Label
        PortList                matlab.ui.control.DropDown
        BFix                    matlab.ui.control.Button
        LRGB                    matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        Label                   matlab.ui.control.Label
        LightEdit               matlab.ui.control.NumericEditField
        LabelSemicircularGauge  matlab.ui.control.Label
        LightGauge              matlab.ui.control.SemicircularGauge
    end


    properties (Access = private)
        s % serial port
        values = zeros(1, 3); % knobs values (K1, K2, K3)
        values_array; % array of color values in time
        light_value % light sensor value
        light_array % array of light sensor values in time
        time % time array
        t % timer (for update GUI and send data)
        % define colors
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
        GRAY_COLOR = [0.4706 0.5059 0.4706];
    end

    methods (Access = private)
        
        function SendData(app)
            % read swithes values from GUI into 1x3 array
            swithes = [app.Sw1.Value, app.Sw2.Value, app.Sw3.Value];
            % calculate color values taking into account switches postions
            current_values = app.values .* swithes; % knobs values (K1, K2, K3) with considering swithes
            
            % update color values array 
            % (discard the 1-st element, append the new element)
            app.values_array = [app.values_array(2 : end, :); current_values];
            % update light sensor values array
            app.light_array = [app.light_array(2 : end); app.light_value];
            
            % plot light sensor values as an area in GUI
            area(app.Axes, app.time, app.light_array, 'FaceColor', 'yellow', 'FaceAlpha', 0.5, 'EdgeColor', 'yellow');
            hold(app.Axes, 'on'); % freeze current axes
            % plot colors values in GUI
            plot(app.Axes, app.time, app.values_array, 'LineWidth', 2);
            hold(app.Axes, 'off'); % unfreeze axes
            ylim(app.Axes, [0 100]); % limit Y-axis
            
            % update LightGauge and LightEdit with the latest light value
            app.LightGauge.Value = app.light_array(end);
            app.LightEdit.Value = app.light_array(end);
            
            % change color of GUI RGB lamp
            app.LRGB.Color = current_values / 100; % scale color values to 0..1 range
            
            % if output buffer is empty write values to serial port in asyncronous mode
            if app.s.BytesToOutput == 0
                current_values = 0.5 * current_values; % decrease maximum lightness to prevent your eyes from burning
                values_to_send = uint8(2.55 * (100 - current_values)); % scale color values to 255..0 uint8 range
                fwrite(app.s, values_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
            end
        end

        
        function ReadData(app)
            % read and scale data from serial port 
            app.light_value = double(fread(app.s, 1, 'uint16')) / 10;
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % fixed time step of calling SendData function
            time_step = 0.1;
            
            % set up swithes values
            app.Sw1.ItemsData = [0 1];
            app.Sw2.ItemsData = [0 1];
            app.Sw3.ItemsData = [0 1];
            % bound swithes with their lamps
            app.Sw1.UserData = app.L1;
            app.Sw2.UserData = app.L2;
            app.Sw3.UserData = app.L3;
            % mark knobs with index numbers
            app.K1.UserData = 1;
            app.K2.UserData = 2;
            app.K3.UserData = 3;
            % define lamps colors
            app.L1.Color = app.GREEN_COLOR;
            app.L2.Color = app.GREEN_COLOR;
            app.L3.Color = app.GREEN_COLOR;
            
            % read knob values into 1x3 array
            app.values = [app.K1.Value, app.K2.Value, app.K3.Value];
            % define time vector for plotting
            app.time = (0 : time_step : 10)';
            % define color and light sensor values arrays for plotting
            app.values_array = zeros(length(app.time), 3);
            app.light_array = zeros(length(app.time), 1);
            % define light sensor value
            app.light_value = 0;
            
            % define color order fot plotting            
            app.Axes.ColorOrder = [0.850,  0.325,  0.098; ...
                                   0.466,  0.674,  0.188; ...
                                   0.000,  0.447,  0.741; ...
                                   0.839,  0.839,  0.024];
            % set up axes
            app.Axes.XLim = [0 10];
            app.Axes.YLim = [0 100];
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.SendData;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = time_step;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.t.running, 'on')
                stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = app.RED_COLOR;
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if strcmp(app.t.running, 'off')
                app.s = serial(app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BytesAvailableFcnCount = 2;
                app.s.BytesAvailableFcnMode = 'byte';
                app.s.BytesAvailableFcn = @(~, ~)app.ReadData;
                fopen(app.s);
                start(app.t);
                app.Lamp.Color = app.GREEN_COLOR;
            end
        end

        % Value changing function: K1, K2, K3
        function KValueChanging(app, event)
            % read knob value if it is changing
            knob = event.Source; % define knob
            i = knob.UserData; % extract knob index (1:red, 2:green, 3:blue)
            app.values(i) = event.Value; % change appropriate element of values vector
        end

        % Value changed function: Sw1, Sw2, Sw3
        function SwValueChanged(app, event)
            % change lamp color if switch value has changed
            sw = event.Source; % define switch
            lamp = sw.UserData; % extract a pointer to corresponding lamp
            if sw.Value
                lamp.Color = app.GREEN_COLOR;
            else 
                lamp.Color = app.GRAY_COLOR;
            end
        end

        % Button pushed function: BFix
        function BFixPushed(app, event)
            % delete all serial port objets from MATLAB memory
            % (Instrument Control Toolbox required)
            delete(instrfind);
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Instrument Control over Serial';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Values')
            xlabel(app.Axes, 'Time, sec')
            app.PanelR = uipanel(app.UIFigure);
            app.PanelR.Title = 'RED LED';
            app.K1 = uiknob(app.PanelR, 'continuous');
            app.K1.ValueChangingFcn = createCallbackFcn(app, @KValueChanging, true);
            app.K1.Value = 100;
            app.L1 = uilamp(app.PanelR);
            app.Sw1 = uiswitch(app.PanelR, 'slider');
            app.Sw1.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.Sw1.Value = 'On';
            app.PanelG = uipanel(app.UIFigure);
            app.PanelG.Title = 'GREEN LED';
            app.Sw2 = uiswitch(app.PanelG, 'slider');
            app.Sw2.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.Sw2.Value = 'On';
            app.K2 = uiknob(app.PanelG, 'continuous');
            app.K2.ValueChangingFcn = createCallbackFcn(app, @KValueChanging, true);
            app.K2.Value = 100;
            app.L2 = uilamp(app.PanelG);
            app.PanelB = uipanel(app.UIFigure);
            app.PanelB.Title = 'BLUE LED';
            app.Sw3 = uiswitch(app.PanelB, 'slider');
            app.Sw3.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.Sw3.Value = 'On';
            app.K3 = uiknob(app.PanelB, 'continuous');
            app.K3.ValueChangingFcn = createCallbackFcn(app, @KValueChanging, true);
            app.K3.Value = 100;
            app.L3 = uilamp(app.PanelB);
            app.PanelCon = uipanel(app.UIFigure);
            app.PanelCon.Title = '      Connection';
            app.BDiscon = uibutton(app.PanelCon, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.BCon = uibutton(app.PanelCon, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.LabelDropDown = uilabel(app.PanelCon);
            app.LabelDropDown.Text = 'Port';
            app.PortList = uidropdown(app.PanelCon);
            app.BFix = uibutton(app.PanelCon, 'push');
            app.BFix.ButtonPushedFcn = createCallbackFcn(app, @BFixPushed, true);
            app.BFix.Text = 'Fix and refresh';
            app.LRGB = uilamp(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'RGB';
            app.LightEdit = uieditfield(app.UIFigure, 'numeric');
            app.LightEdit.ValueDisplayFormat = '%.0f';
            app.LightEdit.Editable = 'off';
            app.LabelSemicircularGauge = uilabel(app.UIFigure);
            app.LabelSemicircularGauge.Text = 'Illuminance';
            app.LightGauge = uigauge(app.UIFigure, 'semicircular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = rgb_control_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end