classdef rgb_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PanelCon                    matlab.ui.container.Panel
        BDiscon                     matlab.ui.control.Button
        BConectar                   matlab.ui.control.Button
        LabelDropDown               matlab.ui.control.Label
        PortList                    matlab.ui.control.DropDown
        BFix                        matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        GraficarButton              matlab.ui.control.Button
        LaboratoriodelPenduloLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        s ;% serial port
        %values = zeros(1, 3); % knobs values (K1, K2, K3)
        values_array; % array of color values in time
        light_value % light sensor value
        light_array % array of light sensor values in time
        time % time array
        t % timer (for update GUI and send data)
        time_step = 0.1; % fixed time step of calling SendData function
        % define colors
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
        GRAY_COLOR = [0.4706 0.5059 0.4706];
    end

    methods (Access = private)
        
        function SendData(app)
            % read swithes values from GUI into 1x3 array
            %swithes = [app.Sw1.Value, app.Sw2.Value, app.Sw3.Value];
            % calculate color values taking into account switches postions
            %current_values = app.values .* swithes; % knobs values (K1, K2, K3) with considering swithes
            
            % update color values array 
            % (discard the 1-st element, append the new element)
            %app.values_array = [app.values_array(2 : end, :); current_values];
            % update light sensor values array
            %app.light_array = [app.light_array(2 : end); app.light_value];
            
            % plot light sensor values as an area in GUI
            %area(app.Axes, app.time, app.light_array,...
             %   'FaceColor', 'yellow', 'FaceAlpha', 0.5, 'EdgeColor', 'yellow');
            %hold(app.Axes, 'on'); % freeze current axes
            % plot colors values in GUI
           % plot(app.Axes, app.time, app.values_array, 'LineWidth', 2);
            %hold(app.Axes, 'off'); % unfreeze axes
            %ylim(app.Axes, [0 100]); % limit Y-axis
            
            % update LightGauge and LightEdit with the latest light value
            %app.LightGauge.Value = app.light_value;
            %app.LightEdit.Value = app.light_value;
            
            % change color of GUI RGB lamp
            %app.LRGB.Color = current_values / 100; % scale color values to 0..1 range
            
            % if output buffer is empty write values to serial port in asyncronous mode
            if app.s.BytesToOutput == 0
                %current_values = 0.5 * current_values; % decrease maximum brightness to prevent your eyes burning
                %values_to_send = uint8(2.55 * (100 - current_values)); % scale color values to 255..0 uint8 range
                %fwrite(app.s, values_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
            end
        end

        
        function ReadData(app)
            % read and scale data from serial port 
           %app.light_value = double(fread(app.s, 1, 'uint16'));
           %disp(fread(app.s, 1, 'uint16'))%double(fread(app.s, 1, 'uint16')))
           %datos = fscanf(app.s,'%f')
           
           
           %disp(fscanf(app.s,'%f'))%leer el puerto serial
           %aqui puede estar el error de q el boton desconectar no funcione
           
           tmax = 2; % tiempo de captura en s
           % f = 80; % resultado experimental (comprobar)
           f = 100; % resultado experimental (comprobar)


           % inicializar
           j=0;
           % ejecutar bucle cronometrado
           %tic
              a1 = fscanf(app.s);%borra encabezados
              a2 = fscanf(app.s);%borra encabezados
              a3 = fscanf(app.s);%borra encabezados
              a4 = fscanf(app.s);%borra encabezados
%------------------------------------------------------
 %-------crear directorio de archivos datos------------  
              Dire=datestr(now);
              ind=find(Dire==char(32));
              Dire(ind)='-';
              ind=find(Dire==char(58));
              Dire(ind)='-';
              mkdir(Dire);
%-----------------------------------------------------
tstart = tic;
while 1<2
    a=[];

i=1;
while i<tmax*f+1
   % t = toc;
    % leer del puerto serie
    %a = fscanf(s,'%d,%d')';
%     b = fscanf(s1,'%s,%s,%s,%s,%s,%s');
%     b = fscanf(s1,'%f,%f,%f,%f,%f,%f');
    b = fscanf(app.s,'%f');
%     b = fscanf(s1,'%int16,%int16,%int16,%int16,%int16,%int16');
%     b = fscanf(s1);
    a=[a;b'];
    t(i)=toc(tstart);
    i = i+1;
end
% resultado del cronometro
j=j+1;
figure(1);plot(a(:,1),a(:,2));grid on;title(['Fuerza   ' int2str(j)]);ylim([-2000 10000]);drawnow;
figure(2);plot(a(:,1),a(:,3:5));grid on;title(['accel x y z  ' int2str(j)]);ylim([-20 30]);drawnow;

%----------------------------------------------------------------
%----crear directorio archivos datos-----------------------------
    file=datestr(now);
    ind=find(file==char(32));
    file(ind)='-';
    ind=find(file==char(58));
    file(ind)='-';
%     file(ind)='_';
    save([Dire '\' file],'t','a');
%     save(file,'Data','Fs','TSimple');
% file=date;
%-----------------------------------------------------------------
end
           
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set up swithes values
            %app.Sw1.ItemsData = [0 1];
            %app.Sw2.ItemsData = [0 1];
            %app.Sw3.ItemsData = [0 1];
            % bound swithes with their lamps
            %app.Sw1.UserData = app.L1;
            %app.Sw2.UserData = app.L2;
            %app.Sw3.UserData = app.L3;
            % mark knobs with index numbers
            %app.K1.UserData = 1;
            %app.K2.UserData = 2;
            %app.K3.UserData = 3;
            % define lamps colors
            %app.L1.Color = app.GREEN_COLOR;
            %app.L2.Color = app.GREEN_COLOR;
            %app.L3.Color = app.GREEN_COLOR;
            
            % read knob values into 1x3 array
            %app.values = [app.K1.Value, app.K2.Value, app.K3.Value];
            % define time vector for plotting
            app.time = (0 : app.time_step : 10)';
            % define color and light sensor values arrays for plotting
            app.values_array = zeros(length(app.time), 3);
            app.light_array = zeros(length(app.time), 1);
            % define light sensor value
            app.light_value = 0;
            
            % define color order fot plotting            
            %app.Axes.ColorOrder = [0.850,  0.325,  0.098; ...
                                  % 0.466,  0.674,  0.188; ...
                                  % 0.000,  0.447,  0.741; ...
                                  % 0.839,  0.839,  0.024];
            % set up axes
           % app.Axes.XLim = [0 10];
           % app.Axes.YLim = [0 100];
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.SendData;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.time_step;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            %p = instrhwinfo('serial');
            %app.PortList.Items = p.AvailableSerialPorts;
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.t.running, 'on')
                stop(app.t);
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color = app.RED_COLOR;
                disp(app.s)
                
            end
        end

        % Button pushed function: BConectar
        function BConectarButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if strcmp(app.t.running, 'off')
                app.s = serial(app.PortList.Value,'BaudRate',115200);%s,115200,'Terminator','CR/LF');%app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BytesAvailableFcnCount = 2;
                app.s.BytesAvailableFcnMode = 'byte';
                app.s.BytesAvailableFcn = @(~, ~)app.ReadData;
                fopen(app.s);
                start(app.t);
                app.Lamp.Color = app.GREEN_COLOR;
                disp(app.s) %mostrar el estado del puerto serie
                %disp(fscanf(app.s,'%f'))%mostrar lo q recibe
                
            end
        end

        % Callback function
        function KValueChanging(app, event)
            % read knob value if it is changing
            %knob = event.Source; % define knob
            %i = knob.UserData; % extract knob index (1:red, 2:green, 3:blue)
            %app.values(i) = event.Value; % change appropriate element of values vector
        end

        % Callback function
        function SwValueChanged(app, event)
            % change lamp color if switch value has changed
            %sw = event.Source; % define switch
            %lamp = sw.UserData; % extract a pointer to corresponding lamp
            %if sw.Value
             %   lamp.Color = app.GREEN_COLOR;
            %else 
             %   lamp.Color = app.GRAY_COLOR;
            %end
        end

        % Button pushed function: BFix
        function BFixPushed(app, event)
            % delete all serial port objets from MATLAB memory
            % (Instrument Control Toolbox required)
            delete(instrfind);
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            %p = instrhwinfo('serial');----------------------------------------------
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            clear all;
        %   close all;
            clc;
         %  concatena_archivos(m); %concatena m archivos 
          % open the file
          % [filename, pathname] = uigetfile('*.*');
            pathname = uigetdir('seleccione directorio');
          % filename=[pathname filename]; % 'casa-u.TXT';
          % fid = fopen(filename);

            dire = dir([pathname '\' '*.mat']); %carga el directorio
            nArch=round(length(dire)/1);

            display('Concatena.m ... inicio carga archivos  ');
            tic
            j=0;
            dati=[];
            ti=[];

for i=1:nArch,    
   if length(dire(i).name) >16,
    datos = load([pathname '\'  dire(i).name]);
    if size(datos.a)~=[0,0]
        nchan=size(datos.a,2);
        if i==1,
            dati=datos.a;
        elseif (i>1 && (size(dati,2) == size(datos.a,2))),
            dati=[dati;datos.a];
        end
    end
    j=j+1;
    if (i ~= j)
     display([i j]);
    end
   end
end
display('fin carga datos');toc;
%  Fs=10.;
%  Fs=length(dati)/dati(end,1);
 Fs= 1/mean(diff(dati(:,1)));

t=dati(:,1);    %(0:1:length(dati)-1)/Fs;
y=find(pathname =='\');
% dat=dati(:,4);
% ind=find(diff( dat)>0.020);
% dat(ind+1)=dat(ind);
% dat1=dat;
%  ind1=find(diff( dat1 ) < -0.020);
% dat1(ind1+1)=dat1(ind1);
%  ind1=find(diff( dat1 ) < -0.020);
% dat1(ind1+1)=dat1(ind1);
figure;plot(dati(:,1),dati(:,2)/1000);grid on;title(['Fuerza en kp vs tiempo en s. Fsample  ', num2str(Fs,'%5.2f')]); 
figure;plot(dati(:,1),dati(:,3:5));grid on;title(['Aceleracion en m/s2 vs tiempo en s. Fsample  ', num2str(Fs,'%5.2f')]); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Instrument Control over Serial';
            app.PanelCon = uipanel(app.UIFigure);
            app.PanelCon.Title = '      Connection';
            app.BDiscon = uibutton(app.PanelCon, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.BConectar = uibutton(app.PanelCon, 'push');
            app.BConectar.ButtonPushedFcn = createCallbackFcn(app, @BConectarButtonPushed, true);
            app.BConectar.Text = 'Connect';
            app.LabelDropDown = uilabel(app.PanelCon);
            app.LabelDropDown.Text = 'Port';
            app.PortList = uidropdown(app.PanelCon);
            app.PortList.Items = {'COM8', 'Option 2', 'Option 3', 'Option 4'};
            app.PortList.Value = 'COM8';
            app.BFix = uibutton(app.PanelCon, 'push');
            app.BFix.ButtonPushedFcn = createCallbackFcn(app, @BFixPushed, true);
            app.BFix.Text = 'Fix and refresh';
            app.Lamp = uilamp(app.PanelCon);
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LaboratoriodelPenduloLabel = uilabel(app.UIFigure);
            app.LaboratoriodelPenduloLabel.Text = 'Laboratorio del Pendulo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rgb_control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end